classdef Final_work < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PricerTab                      matlab.ui.container.Tab
        PricerResetButton              matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.Panel
        StandardErrorResult            matlab.ui.control.Label
        StandardErrorLabel             matlab.ui.control.Label
        MonteCarloPriceResult          matlab.ui.control.Label
        BlackScholesPriceResult        matlab.ui.control.Label
        MonteCarloPriceLabel           matlab.ui.control.Label
        BlackScholesPriceLabel         matlab.ui.control.Label
        PathsAxes                      matlab.ui.control.UIAxes
        PayoffAxes                     matlab.ui.control.UIAxes
        InputPanel                     matlab.ui.container.Panel
        ComputePriceButton             matlab.ui.control.Button
        MonteCarloPanel                matlab.ui.container.Panel
        SeedEditField                  matlab.ui.control.NumericEditField
        SeedEditFieldLabel             matlab.ui.control.Label
        FixedSeedCheckBox              matlab.ui.control.CheckBox
        NstepsEditField                matlab.ui.control.NumericEditField
        NstepsEditFieldLabel           matlab.ui.control.Label
        NpathsEditField                matlab.ui.control.NumericEditField
        NpathsEditFieldLabel           matlab.ui.control.Label
        OptionTypeDropDown             matlab.ui.control.DropDown
        OptionTypeDropDownLabel        matlab.ui.control.Label
        TEditField                     matlab.ui.control.NumericEditField
        TMaturityinyearsLabel          matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        SigmaVolatilityEditFieldLabel  matlab.ui.control.Label
        rEditField                     matlab.ui.control.NumericEditField
        rRateEditFieldLabel            matlab.ui.control.Label
        KEditField                     matlab.ui.control.NumericEditField
        KStrikeEditFieldLabel          matlab.ui.control.Label
        S0EditField                    matlab.ui.control.NumericEditField
        S0SpotEditFieldLabel           matlab.ui.control.Label
        SimulatorTab                   matlab.ui.container.Tab
        SimulatorResetButton           matlab.ui.control.Button
        OutputPanel_2                  matlab.ui.container.Panel
        S_tMeanResult                  matlab.ui.control.Label
        S_tStdResult                   matlab.ui.control.Label
        S_tStdLabel                    matlab.ui.control.Label
        S_tMeanLabel                   matlab.ui.control.Label
        SimTerminalAxes                matlab.ui.control.UIAxes
        SimPathsAxes                   matlab.ui.control.UIAxes
        InputSimPanel                  matlab.ui.container.Panel
        SimulateButton                 matlab.ui.control.Button
        MonteCarloSimPanel             matlab.ui.container.Panel
        SeedSimEditField               matlab.ui.control.NumericEditField
        SeedEditField_2Label           matlab.ui.control.Label
        FixedSeedSimCheckBox           matlab.ui.control.CheckBox
        NstepsSimEditField             matlab.ui.control.NumericEditField
        NstepsEditField_2Label         matlab.ui.control.Label
        NpathsSimEditField             matlab.ui.control.NumericEditField
        NpathsEditField_2Label         matlab.ui.control.Label
        TSimEditField                  matlab.ui.control.NumericEditField
        TMaturityinyearsLabel_2        matlab.ui.control.Label
        SigmaSimEditField              matlab.ui.control.NumericEditField
        SigmaVolatilityEditFieldLabel_2  matlab.ui.control.Label
        rSimEditField                  matlab.ui.control.NumericEditField
        rRateEditFieldLabel_2          matlab.ui.control.Label
        S0SimEditField                 matlab.ui.control.NumericEditField
        S0SpotEditFieldLabel_2         matlab.ui.control.Label
        About                          matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        PricerTabTextArea              matlab.ui.control.TextArea
        PricerTabTextAreaLabel         matlab.ui.control.Label
        SimulatorTabTextArea           matlab.ui.control.TextArea
        SimulatorTabTextAreaLabel      matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function ok = validateInputs(app, mode)
        % mode: "pricer" or "sim", depending on input
        ok = false;

        % Grab values
        S0 = app.S0EditField.Value;
        r = app.rEditField.Value;
        sigma = app.SigmaEditField.Value;
        T = app.TEditField.Value;
        K = app.KEditField.Value;

        if S0 <= 0 || T < 0 || sigma < 0
            uialert(app.UIFigure, 'S0 must be > 0, T >= 0, sigma >= 0.', 'Invalid input');
            return;
        end

        if mode == "pricer"
            if K <= 0
                uialert(app.UIFigure, 'K must be > 0.', 'Invalid input');
                return;
            end
            Npaths = app.NpathsEditField.Value;
            if Npaths < 1000
                uialert(app.UIFigure, 'Use at least 1000 Monte Carlo paths.', 'Invalid input');
                return;
            end
        else
            Nsteps = app.NstepsEditField.Value;
            if Nsteps < 1
                uialert(app.UIFigure, 'Nsteps must be >= 1.', 'Invalid input');
                return;
            end
        end

        ok = true;
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add pricing functions folder to path
            appRoot = fileparts(mfilename('fullpath'));
            addpath(fullfile(appRoot, '..', 'pricing'));
        end

        % Button pushed function: ComputePriceButton
        function ComputePriceButtonPushed(app, event)
            % Assign variable inputs
            S0    = app.S0EditField.Value;
            K     = app.KEditField.Value;
            r     = app.rEditField.Value;
            sigma = app.SigmaEditField.Value;
            T     = app.TEditField.Value;
        
            Npaths = app.NpathsEditField.Value;
            Nsteps = app.NstepsEditField.Value;
        
            optionType = lower(app.OptionTypeDropDown.Value); % 'call' or 'put'
    
            % Minimal validation for inputs
            if S0 <= 0 || K <= 0 || T < 0 || sigma < 0 || Npaths < 1000 || Nsteps < 1
                uialert(app.UIFigure, "Revisa los inputs: S0,K>0, T>=0, sigma>=0, Npaths>=1000, Nsteps>=1.", "Invalid input");
                return;
            end
        
            % Optional Seed
            if app.FixedSeedCheckBox.Value
                rng(app.SeedEditField.Value, "twister");
            end
        
            % Calculate price with Black-Scholes
            bs = black_scholes_price(S0, K, r, sigma, T, optionType);
        

            % Calculate monte carlo price
            [mc, stderr, discPayoffs, paths] = mc_euro_price(S0, K, r, sigma, T, Nsteps, Npaths, optionType);
        
            % result output
            app.BlackScholesPriceResult.Text  = sprintf("%.6f", bs);
            app.BlackScholesPriceResult.FontSize = 12;
            app.MonteCarloPriceResult.Text  = sprintf("%.6f", mc);
            app.MonteCarloPriceResult.FontSize = 12;
            app.StandardErrorResult.Text  = sprintf("%.6f", stderr);
            app.StandardErrorResult.FontSize = 12;
        
            % Graph
            cla(app.PayoffAxes);
            histogram(app.PayoffAxes, discPayoffs, 50);
            title(app.PayoffAxes, "Discounted payoff distribution");
        
            cla(app.PathsAxes);
        if ~isempty(paths)
            nShow = min(30, size(paths,2));     % show up to 30 paths
            plot(app.PathsAxes, paths(:, 1:nShow));
            title(app.PathsAxes, "Sample GBM paths");
            xlabel(app.PathsAxes, "Step");
            ylabel(app.PathsAxes, "S");
        else
            title(app.PathsAxes, "No paths returned");
        end
        
            drawnow;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Inputs to values
            S0    = app.S0SimEditField.Value;
            r     = app.rSimEditField.Value;
            sigma = app.SigmaSimEditField.Value;
            T     = app.TSimEditField.Value;
        
            nSteps = app.NstepsSimEditField.Value;
            nPaths = app.NpathsSimEditField.Value;
        
            % Minimal validation for inputs
            if S0 <= 0 || T < 0 || sigma < 0 || nSteps < 1 || nPaths < 10
                uialert(app.UIFigure, ...
                    "Inputs inválidos: S0>0, T>=0, sigma>=0, nSteps>=1, nPaths>=10.", ...
                    "Invalid input");
                return;
            end
        
            % Block sim button during simulation run
            app.SimulateButton.Enable = "off";
            cleanupObj = onCleanup(@() set(app.SimulateButton, "Enable", "on"));
            drawnow;
        
            % Seed ()
            if app.FixedSeedSimCheckBox.Value
                rng(app.SeedSimEditField.Value, "twister");
            end
        
            % GBM simulation
            paths = simulate_gbm_paths(S0, r, sigma, T, nSteps, nPaths);
        
            % Plot 1 sample paths (30 paths for visualization)
            cla(app.SimPathsAxes);
            nShow = min(30, size(paths,2));
            plot(app.SimPathsAxes, paths(:, 1:nShow));
            title(app.SimPathsAxes, "Simulated GBM paths");
            xlabel(app.SimPathsAxes, "Step");
            ylabel(app.SimPathsAxes, "S_t");
            grid(app.SimPathsAxes, "on");
        

            % Terminal distribution
            ST = paths(end, :);


            % Plot 2
            cla(app.SimTerminalAxes);
            histogram(app.SimTerminalAxes, ST, 50);
            title(app.SimTerminalAxes, "Terminal price distribution (S_T)");
            xlabel(app.SimTerminalAxes, "S_T");
            ylabel(app.SimTerminalAxes, "Frequency");
            grid(app.SimTerminalAxes, "on");
        
            % Outputs
            app.S_tMeanResult.Text = sprintf("%.6f", mean(ST));
            app.S_tMeanResult.FontSize = 12;
            app.S_tStdResult.Text = sprintf("%.6f", std(ST));
            app.S_tStdResult.FontSize = 12;
        
            drawnow;
        end

        % Value changed function: FixedSeedSimCheckBox
        function FixedSeedSimCheckBoxValueChanged(app, event)
            if app.FixedSeedSimCheckBox.Value
                app.SeedSimEditField.Enable = "on";
            else
                app.SeedSimEditField.Enable = "off";
            end
        end

        % Value changed function: FixedSeedCheckBox
        function FixedSeedCheckBoxValueChanged(app, event)
            if app.FixedSeedCheckBox.Value
                app.SeedEditField.Enable = "on";
            else
                app.SeedEditField.Enable = "off";
            end
        end

        % Button pushed function: PricerResetButton
        function PricerResetButtonPushed(app, event)
            
        
            % Default values
            app.S0EditField.Value    = 100;
            app.KEditField.Value     = 102;
            app.rEditField.Value     = 0.05;
            app.SigmaEditField.Value = 0.20;
            app.TEditField.Value     = 1.0;
        
            app.NstepsEditField.Value = 252;
            app.NpathsEditField.Value = 50000;   
        
            % Dropdown
            app.OptionTypeDropDown.Value = "Call";
        
            % Seed controls
            app.FixedSeedCheckBox.Value = false;
            app.SeedEditField.Value = 1;
            app.SeedEditField.Enable = "off";
        
            % Outputs
            app.BlackScholesPriceResult.Text = "—";
            app.BlackScholesPriceResult.FontSize = 24;
            app.MonteCarloPriceResult.Text   = "—";
            app.MonteCarloPriceResult.FontSize = 24;
            app.StandardErrorResult.Text     = "—";
            app.StandardErrorResult.FontSize = 24;
        
            % Clear plots
            cla(app.PayoffAxes);
            title(app.PayoffAxes, "");
        
            cla(app.PathsAxes);
            title(app.PathsAxes, "");
        
            drawnow;
        end

        % Button pushed function: SimulatorResetButton
        function SimulatorResetButtonPushed(app, event)
            % Defaults
            app.S0SimEditField.Value    = 100;
            app.rSimEditField.Value     = 0.05;
            app.SigmaSimEditField.Value = 0.20;
            app.TSimEditField.Value     = 1.0;
        
            app.NstepsSimEditField.Value = 252;
            app.NpathsSimEditField.Value = 1000;
        
            % Seed controls
            app.FixedSeedSimCheckBox.Value = false;
            app.SeedSimEditField.Value = 1;
            app.SeedSimEditField.Enable = "off";
        
            % Outputs 
            app.S_tMeanResult.Text = "—";
            app.S_tMeanResult.FontSize = 24;
            app.S_tStdResult.Text  = "—";
            app.S_tStdResult.FontSize = 24;
        
            % Clear plots
            cla(app.SimPathsAxes);
            title(app.SimPathsAxes, "");
        
            cla(app.SimTerminalAxes);
            title(app.SimTerminalAxes, "");
        
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PricerTab = uitab(app.TabGroup);
            app.PricerTab.Title = 'Pricer';
            app.InputPanel = uipanel(app.PricerTab);
            app.InputPanel.Title = 'Input';
            app.S0SpotEditFieldLabel = uilabel(app.InputPanel);
            app.S0SpotEditFieldLabel.Text = 'S0 (Spot)';
            app.S0EditField = uieditfield(app.InputPanel, 'numeric');
            app.S0EditField.Limits = [1e-09 Inf];
            app.S0EditField.Value = 100;
            app.KStrikeEditFieldLabel = uilabel(app.InputPanel);
            app.KStrikeEditFieldLabel.Text = 'K (Strike)';
            app.KEditField = uieditfield(app.InputPanel, 'numeric');
            app.KEditField.Limits = [0 Inf];
            app.KEditField.Value = 102;
            app.rRateEditFieldLabel = uilabel(app.InputPanel);
            app.rRateEditFieldLabel.Text = 'r (Rate)';
            app.rEditField = uieditfield(app.InputPanel, 'numeric');
            app.rEditField.Value = 0.05;
            app.SigmaVolatilityEditFieldLabel = uilabel(app.InputPanel);
            app.SigmaVolatilityEditFieldLabel.Text = 'Sigma (Volatility)';
            app.SigmaEditField = uieditfield(app.InputPanel, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.SigmaEditField.Value = 0.2;
            app.TMaturityinyearsLabel = uilabel(app.InputPanel);
            app.TMaturityinyearsLabel.Text = 'T (Maturity in years)';
            app.TEditField = uieditfield(app.InputPanel, 'numeric');
            app.TEditField.Value = 1;
            app.OptionTypeDropDownLabel = uilabel(app.InputPanel);
            app.OptionTypeDropDownLabel.Text = 'Option Type';
            app.OptionTypeDropDown = uidropdown(app.InputPanel);
            app.OptionTypeDropDown.Items = {'Call', 'Put'};
            app.OptionTypeDropDown.Value = 'Call';
            app.MonteCarloPanel = uipanel(app.InputPanel);
            app.MonteCarloPanel.Title = 'Monte Carlo';
            app.NpathsEditFieldLabel = uilabel(app.MonteCarloPanel);
            app.NpathsEditFieldLabel.Text = 'Npaths';
            app.NpathsEditField = uieditfield(app.MonteCarloPanel, 'numeric');
            app.NpathsEditField.Value = 50000;
            app.NstepsEditFieldLabel = uilabel(app.MonteCarloPanel);
            app.NstepsEditFieldLabel.Text = 'Nsteps';
            app.NstepsEditField = uieditfield(app.MonteCarloPanel, 'numeric');
            app.NstepsEditField.Value = 252;
            app.FixedSeedCheckBox = uicheckbox(app.MonteCarloPanel);
            app.FixedSeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedSeedCheckBoxValueChanged, true);
            app.FixedSeedCheckBox.Text = 'Fixed Seed';
            app.SeedEditFieldLabel = uilabel(app.MonteCarloPanel);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.MonteCarloPanel, 'numeric');
            app.SeedEditField.Value = 1;
            app.ComputePriceButton = uibutton(app.InputPanel, 'push');
            app.ComputePriceButton.ButtonPushedFcn = createCallbackFcn(app, @ComputePriceButtonPushed, true);
            app.ComputePriceButton.Text = 'Compute Price';
            app.OutputPanel = uipanel(app.PricerTab);
            app.OutputPanel.Title = 'Output';
            app.PayoffAxes = uiaxes(app.OutputPanel);
            xlabel(app.PayoffAxes, 'Discounted payoff')
            ylabel(app.PayoffAxes, 'Frequency')
            zlabel(app.PayoffAxes, 'Z')
            app.PathsAxes = uiaxes(app.OutputPanel);
            xlabel(app.PathsAxes, 'Time step')
            ylabel(app.PathsAxes, 'Underlying price S_t')
            zlabel(app.PathsAxes, 'Z')
            app.BlackScholesPriceLabel = uilabel(app.OutputPanel);
            app.BlackScholesPriceLabel.Text = 'Black-Scholes Price  =';
            app.MonteCarloPriceLabel = uilabel(app.OutputPanel);
            app.MonteCarloPriceLabel.Text = 'Monte Carlo Price     =';
            app.BlackScholesPriceResult = uilabel(app.OutputPanel);
            app.BlackScholesPriceResult.Text = '-';
            app.MonteCarloPriceResult = uilabel(app.OutputPanel);
            app.MonteCarloPriceResult.Text = '-';
            app.StandardErrorLabel = uilabel(app.OutputPanel);
            app.StandardErrorLabel.Text = 'Standard Error           =';
            app.StandardErrorResult = uilabel(app.OutputPanel);
            app.StandardErrorResult.Text = '-';
            app.PricerResetButton = uibutton(app.PricerTab, 'push');
            app.PricerResetButton.ButtonPushedFcn = createCallbackFcn(app, @PricerResetButtonPushed, true);
            app.PricerResetButton.Text = 'Reset';
            app.SimulatorTab = uitab(app.TabGroup);
            app.SimulatorTab.Title = 'Simulator';
            app.InputSimPanel = uipanel(app.SimulatorTab);
            app.InputSimPanel.Title = 'Input';
            app.S0SpotEditFieldLabel_2 = uilabel(app.InputSimPanel);
            app.S0SpotEditFieldLabel_2.Text = 'S0 (Spot)';
            app.S0SimEditField = uieditfield(app.InputSimPanel, 'numeric');
            app.S0SimEditField.Limits = [1e-09 Inf];
            app.S0SimEditField.Value = 100;
            app.rRateEditFieldLabel_2 = uilabel(app.InputSimPanel);
            app.rRateEditFieldLabel_2.Text = 'r (Rate)';
            app.rSimEditField = uieditfield(app.InputSimPanel, 'numeric');
            app.rSimEditField.Value = 0.05;
            app.SigmaVolatilityEditFieldLabel_2 = uilabel(app.InputSimPanel);
            app.SigmaVolatilityEditFieldLabel_2.Text = 'Sigma (Volatility)';
            app.SigmaSimEditField = uieditfield(app.InputSimPanel, 'numeric');
            app.SigmaSimEditField.Limits = [0 Inf];
            app.SigmaSimEditField.Value = 0.2;
            app.TMaturityinyearsLabel_2 = uilabel(app.InputSimPanel);
            app.TMaturityinyearsLabel_2.Text = 'T (Maturity in years)';
            app.TSimEditField = uieditfield(app.InputSimPanel, 'numeric');
            app.TSimEditField.Value = 1;
            app.MonteCarloSimPanel = uipanel(app.InputSimPanel);
            app.MonteCarloSimPanel.Title = 'Monte Carlo';
            app.NpathsEditField_2Label = uilabel(app.MonteCarloSimPanel);
            app.NpathsEditField_2Label.Text = 'Npaths';
            app.NpathsSimEditField = uieditfield(app.MonteCarloSimPanel, 'numeric');
            app.NpathsSimEditField.Value = 1000;
            app.NstepsEditField_2Label = uilabel(app.MonteCarloSimPanel);
            app.NstepsEditField_2Label.Text = 'Nsteps';
            app.NstepsSimEditField = uieditfield(app.MonteCarloSimPanel, 'numeric');
            app.NstepsSimEditField.Value = 252;
            app.FixedSeedSimCheckBox = uicheckbox(app.MonteCarloSimPanel);
            app.FixedSeedSimCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedSeedSimCheckBoxValueChanged, true);
            app.FixedSeedSimCheckBox.Text = 'Fixed Seed';
            app.SeedEditField_2Label = uilabel(app.MonteCarloSimPanel);
            app.SeedEditField_2Label.Text = 'Seed';
            app.SeedSimEditField = uieditfield(app.MonteCarloSimPanel, 'numeric');
            app.SeedSimEditField.Value = 1;
            app.SimulateButton = uibutton(app.InputSimPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate Button';
            app.OutputPanel_2 = uipanel(app.SimulatorTab);
            app.OutputPanel_2.Title = 'Output';
            app.SimPathsAxes = uiaxes(app.OutputPanel_2);
            xlabel(app.SimPathsAxes, 'Time step')
            ylabel(app.SimPathsAxes, 'Underlying price S_t')
            zlabel(app.SimPathsAxes, 'Z')
            app.SimTerminalAxes = uiaxes(app.OutputPanel_2);
            xlabel(app.SimTerminalAxes, 'Terminal price S_T')
            ylabel(app.SimTerminalAxes, 'Frequency')
            zlabel(app.SimTerminalAxes, 'Z')
            app.S_tMeanLabel = uilabel(app.OutputPanel_2);
            app.S_tMeanLabel.Text = 'S_t Mean               =';
            app.S_tStdLabel = uilabel(app.OutputPanel_2);
            app.S_tStdLabel.Text = 'S_t Std                   =';
            app.S_tStdResult = uilabel(app.OutputPanel_2);
            app.S_tStdResult.Text = '-';
            app.S_tMeanResult = uilabel(app.OutputPanel_2);
            app.S_tMeanResult.Text = '-';
            app.SimulatorResetButton = uibutton(app.SimulatorTab, 'push');
            app.SimulatorResetButton.ButtonPushedFcn = createCallbackFcn(app, @SimulatorResetButtonPushed, true);
            app.SimulatorResetButton.Text = 'Reset';
            app.About = uitab(app.TabGroup);
            app.About.Title = 'About';
            app.SimulatorTabTextAreaLabel = uilabel(app.About);
            app.SimulatorTabTextAreaLabel.Text = 'Simulator Tab';
            app.SimulatorTabTextArea = uitextarea(app.About);
            app.SimulatorTabTextArea.Value = {'Inputs: S_0, r, Sigma, T, number of steps, number of paths.'; 'Outputs: simulated GBM price paths and the terminal distribution of S_T.'; 'Plots:'; '	•	Simulated GBM paths: sample trajectories S_t over discrete time steps.'; '	•	Terminal price distribution: histogram of S_T.'; ''; 'If Fixed seed is enabled, the random number generator is initialized with the selected seed value so that repeated runs produce identical results. If disabled, each run uses new random samples.'; 'Notes'; '	•	T is measured in years and r, \sigma are annualized.'; '	•	Large values of number of paths and number of steps increase accuracy but require more computation time and memory.'; '	•	The Black–Scholes model assumes lognormal dynamics, constant volatility, and continuous trading under a risk-neutral measure.'};
            app.PricerTabTextAreaLabel = uilabel(app.About);
            app.PricerTabTextAreaLabel.Text = 'Pricer Tab';
            app.PricerTabTextArea = uitextarea(app.About);
            app.PricerTabTextArea.Value = {'Inputs: S_0, K, r, Sigma, T, number of steps, number of paths, option type (call/put).'; 'Outputs:'; '	•	Black–Scholes price: closed-form analytical price for a European option.'; '	•	Monte Carlo price: estimate obtained by simulating terminal prices S_T and averaging discounted payoffs.'; '	•	Standard error: uncertainty of the Monte Carlo estimator due to finite sampling.'; ''; 'Plots:'; '	•	Discounted payoff distribution: histogram of e^{-rT}\max(S_T-K,0) (call) or e^{-rT}\max(K-S_T,0) (put).'; '	•	Sample GBM paths: a subset of simulated trajectories S_t.'};
            app.TextArea = uitextarea(app.About);
            app.TextArea.Value = {'Application: European Option Pricing and Monte Carlo Simulation '; 'Author: Diego Alberto Ramírez Madriz'; 'Purpose: '; 'This app provides two tools:'; '       •	Pricing European call/put options using Black–Scholes and Monte Carlo'; '       •	Simulating Geometric Brownian Motion paths to visualize the underlying dynamics'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_work

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end